classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        OutputLabel_2          matlab.ui.control.Label
        OutputLabel            matlab.ui.control.Label
        ReferenceImageButton   matlab.ui.control.Button
        InputImageButton       matlab.ui.control.Button
        EnhancmentButton       matlab.ui.control.Button
        bLabel                 matlab.ui.control.Label
        aLabel                 matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        IMAGEENHANCEMENTLabel  matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        Input2EditField        matlab.ui.control.NumericEditField
        Input1EditField        matlab.ui.control.NumericEditField
        UIAxes2_3              matlab.ui.control.UIAxes
        UIAxes2_2              matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes_5               matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageIputVariable = nan
        ImageIputVariableRef = nan
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes);
            disableDefaultInteractivity(app.UIAxes_3);
            disableDefaultInteractivity(app.UIAxes_5);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            cla(app.UIAxes2_2)
            cla(app.UIAxes_3)
            app.UIAxes_3.Visible = "on";
            if value == "Brightening"
                app.Input1EditField.Visible = "on";
                app.aLabel.Visible = "on";
                app.Input2EditField.Visible = "on";
                app.bLabel.Visible = "on";
                app.aLabel.Text = "a";
                app.bLabel.Text = "b";
                app.OutputLabel_2.Visible = "on";
                app.OutputLabel.Visible = "off";
                app.ReferenceImageButton.Visible = "off";
                app.UIAxes2_3.Visible = "off";
                app.UIAxes_5.Visible = "off";
                cla(app.UIAxes2_3)
                cla(app.UIAxes_5)
            elseif value == "Transformation Log"
                app.Input1EditField.Visible = "on";
                app.aLabel.Visible = "on";
                app.Input2EditField.Visible = "off";
                app.bLabel.Visible = "off";
                app.aLabel.Text = "c";
                app.OutputLabel_2.Visible = "on";
                app.OutputLabel.Visible = "off";
                app.ReferenceImageButton.Visible = "off";
                app.UIAxes2_3.Visible = "off";
                app.UIAxes_5.Visible = "off";
                cla(app.UIAxes2_3)
                cla(app.UIAxes_5)
            elseif value == "Transformation Power"
                app.Input1EditField.Visible = "on";
                app.aLabel.Visible = "on";
                app.Input2EditField.Visible = "on";
                app.bLabel.Visible = "on";
                app.aLabel.Text = "c";
                app.bLabel.Text = "g";
                app.OutputLabel_2.Visible = "on";
                app.OutputLabel.Visible = "off";
                app.ReferenceImageButton.Visible = "off";
                app.UIAxes2_3.Visible = "off";
                app.UIAxes_5.Visible = "off";
                cla(app.UIAxes2_3)
                cla(app.UIAxes_5)
            else 
                app.Input1EditField.Visible = "off";
                app.aLabel.Visible = "off";
                app.Input2EditField.Visible = "off";
                app.bLabel.Visible = "off";
                if value == "Histogram Matching"
                    cla(app.UIAxes2_2)
                    cla(app.UIAxes_3)
                    app.ImageIputVariableRef = nan;
                    app.OutputLabel_2.Visible = "off";
                    app.OutputLabel.Visible = "on";
                    app.ReferenceImageButton.Visible = "on";
                    app.UIAxes_3.Visible = "on";
                    app.UIAxes2_3.Visible = "on";
                    app.UIAxes_5.Visible = "on";
                else
                    cla(app.UIAxes2_3)
                    cla(app.UIAxes_5)
                    app.OutputLabel_2.Visible = "on";
                    app.OutputLabel.Visible = "off";
                    app.ReferenceImageButton.Visible = "off";
                    app.UIAxes2_3.Visible = "off";
                    app.UIAxes_5.Visible = "off";
                end

            end
        end

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp'},"Open File");
            fullpath = strcat(pathname,filename);
            if(fullpath == "")
                warndlg("File Not Found")
            else
                app.ImageIputVariable = imread(fullpath);
                imshow(app.ImageIputVariable,'Parent',app.UIAxes);
                histimg = imageHistogram(app.ImageIputVariable);
                plot(app.UIAxes2,histimg);
                axis(app.UIAxes2,'auto')
            end
            
        end

        % Button pushed function: ReferenceImageButton
        function ReferenceImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp'},"Open File");
            fullpath = strcat(pathname,filename);
            if(fullpath == "")
                warndlg("File Not Found")
            else
                app.ImageIputVariableRef = imread(fullpath);
                imshow(app.ImageIputVariableRef,'Parent',app.UIAxes_3);
                histimg = imageHistogram(app.ImageIputVariableRef);
                plot(app.UIAxes2_2,histimg);
                axis(app.UIAxes2_2,'auto')
            end
        end

        % Button pushed function: EnhancmentButton
        function EnhancmentButtonPushed(app, event)
            value = app.DropDown.Value;
            try
                if(isnan(app.ImageIputVariable))
                    warndlg("Please Input Image First","Input Image")
                else
                    if(value == "Brightening")
                        result = brightenImage(app.ImageIputVariable,app.Input1EditField.Value,app.Input2EditField.Value);
                        imshow(result,'Parent',app.UIAxes_3);
                        histimagebright = imageHistogram(result);
                        plot(app.UIAxes2_2,histimagebright)
                        axis(app.UIAxes2_2,'auto')
                    elseif value == "Negative"
                        resultneg = negativeImage(app.ImageIputVariable);
                        imshow(resultneg,'Parent',app.UIAxes_3)
                        histimgneg = imageHistogram(resultneg);
                        plot(app.UIAxes2_2,histimgneg)
                        axis(app.UIAxes2_2,'auto')
                    elseif value == "Transformation Log"
                        copy = app.ImageIputVariable;
                        resultlog = transformLogImage(copy,app.Input1EditField.Value);
                        imshow(resultlog,'Parent',app.UIAxes_3);
                        resultim2 = uint8(floor(255 .* resultlog));
                        histimglog = imageHistogram(resultim2);
                        plot(app.UIAxes2_2,histimglog)
                        axis(app.UIAxes2_2,'auto')
                    elseif value == "Transformation Power"
                        resultpower = transformPowerImage(app.ImageIputVariable,app.Input1EditField.Value,app.Input2EditField.Value);
                        imshow(resultpower,'Parent',app.UIAxes_3);
                        resultpower = uint8(floor(255 .* resultpower));
                        histimgpwr = imageHistogram(resultpower);
                        plot(app.UIAxes2_2,histimgpwr)
                        axis(app.UIAxes2_2,'auto')
                    elseif value == "Contrast Stretching"
                        result = contrastStretchingImage(app.ImageIputVariable);
                        imshow(result,'Parent',app.UIAxes_3);
                        resultim2 = uint8(floor(255 .* result));
                        histresult = imageHistogram(resultim2);
                        plot(app.UIAxes2_2,histresult)
                        axis(app.UIAxes2_2,'auto')
                    elseif value == "Histogram Equalization"
                        result = histEqImage(app.ImageIputVariable);
                        imshow(result,'Parent',app.UIAxes_3);
                        histresult = imageHistogram(result);
                        plot(app.UIAxes2_2,histresult)
                        axis(app.UIAxes2_2,'auto')
                    elseif value == "Histogram Matching"
                        if(isnan(app.ImageIputVariableRef))
                            warndlg("Please Input Image Reference First","Input Image")
                        else
                            result = histMatchingImage(app.ImageIputVariableRef,app.ImageIputVariable);
                            imshow(result,'Parent',app.UIAxes_5);
                            histresult = imageHistogram(result);
                            plot(app.UIAxes2_3,histresult)
                            axis(app.UIAxes2_3,'auto')
                        end
                    end
                end
            catch ME
                warndlg("Something Error: "+ME.message,"Error")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            colormap(app.UIAxes, 'parula')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            colormap(app.UIAxes_3, 'parula')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            colormap(app.UIAxes_5, 'parula')
            app.UIAxes_5.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'parula')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            colormap(app.UIAxes2_2, 'parula')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            colormap(app.UIAxes2_3, 'parula')
            app.UIAxes2_3.Visible = 'off';
            app.Input1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Input2EditField = uieditfield(app.UIFigure, 'numeric');
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Brightening', 'Negative', 'Transformation Log', 'Transformation Power', 'Contrast Stretching', 'Histogram Equalization', 'Histogram Matching'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Brightening';
            app.IMAGEENHANCEMENTLabel = uilabel(app.UIFigure);
            app.IMAGEENHANCEMENTLabel.Text = 'IMAGE ENHANCEMENT';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'cat.png');
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b';
            app.EnhancmentButton = uibutton(app.UIFigure, 'push');
            app.EnhancmentButton.ButtonPushedFcn = createCallbackFcn(app, @EnhancmentButtonPushed, true);
            app.EnhancmentButton.Text = 'Enhancment';
            app.InputImageButton = uibutton(app.UIFigure, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Input Image';
            app.ReferenceImageButton = uibutton(app.UIFigure, 'push');
            app.ReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceImageButtonPushed, true);
            app.ReferenceImageButton.Visible = 'off';
            app.ReferenceImageButton.Text = 'Reference Image';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Visible = 'off';
            app.OutputLabel.Text = 'Output';
            app.OutputLabel_2 = uilabel(app.UIFigure);
            app.OutputLabel_2.Text = 'Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end